classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WedgesFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        WedgesFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ConstraintsFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        ConstraintsFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        StrategyFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        StrategyFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        SelectedWedgesTextAreaLabel   matlab.ui.control.Label
        SelectedWedgesTextArea        matlab.ui.control.TextArea
        ConstraintsDataTextAreaLabel  matlab.ui.control.Label
        ConstraintsDataTextArea       matlab.ui.control.TextArea
        StrategyDataTextAreaLabel     matlab.ui.control.Label
        StrategyDataTextArea          matlab.ui.control.TextArea
        ReadFilesButton               matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        DropDownLabel                 matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        wedge_data = {};
        header = [];
        % Initialize variables
    end
    

    methods (Access = private)

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
            value1 = app.WedgesFileNameWithoutQuotesEditField.Value;
            % Initialize variables
            % Make Sounds for errors when applicable
            % Use Warndlg for error
            
            try
                if strfind(value1,'wedges')
                    FID = fopen(value1);
                    app.header = fgetl(FID);
                    % If file is correct, open text file and extract first line
                    
                    app.UITable.ColumnName = strsplit(app.header, ';');
                    % Make first line of file the header of the table
                    
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ';');
                        app.wedge_data = [app.wedge_data ; x];
                    end % end while
                    % Extract each line of the file
                    
                    app.UITable.Data = app.wedge_data;
                    % Display the data in the Table
                    
                    dropmenu = app.wedge_data(:, 1);
                    dropmenu = [dropmenu; {'Clear all'}];
                    
                    app.DropDown.Items = dropmenu;
                    % Display wedge numbers in the Drop Down Menu
                    
                else
                    app.SelectedWedgesTextArea.Value = 'Error: Incorrect file name entered. File name must include the word ''wedges''.';
                    % If the wrong file is selected, display error message
                end % end if
            catch
                app.SelectedWedgesTextArea.Value = 'Error: File not found in MATLAB path';
                % If the file is not in the MATLAB path, display the error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value2 = app.ConstraintsFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value2,'constraints')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value2);
                    while ~feof(FID)
                        x = {fgetl(FID)};
                        data = [data; x];
                    end % end while
                    app.ConstraintsDataTextArea.Value = data;
                    % Display the data in the appropriate text area
                else
                    app.ConstraintsDataTextArea.Value = 'Error: Incorrect file name entered. File name must include the word ''constraints''.';
                    % If the wrong file is selected, display error message
                end % end if
            catch
                app.ConstraintsDataTextArea.Value = 'Error: File not found in MATLAB path';
                % If the file is not in the MATLAB path, display error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value3 = app.StrategyFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value3,'strategy')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value3);
                    while ~feof(FID)
                        x = {fgetl(FID)};
                        data = [data; x];
                    end % end while
                    app.StrategyDataTextArea.Value = data;
                    % Display the data in the appropriate text area
                else
                    app.StrategyDataTextArea.Value = 'Error: Incorrect file name entered. File name must include the word ''strategy''.';
                    % If the wrong file is selected, display error message
                end % end if
            catch
                app.StrategyDataTextArea.Value = 'Error: File not found in MATLAB path';
                % If the file is not in the MATLAB path, display error message
            end % end try
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value1 = app.DropDown.Value;
            value = str2num(value1);
                         header1 = strsplit(app.header, ';');
%                          k = strfind(header1, 'Strategy');
            % Initialize variables
            
                        index = find(contains(header1, 'Strategy'));
                    
            %            for i = 1:length(header1)
            %             if k(i) == {1}
            %                 index = i;
            %                 break
            %             end % end if
            %            end % end for
            % k = strfind(header1(i), 'Strategy');
            %                 break
            %                 end % end if
            %             end % end for
            %             wedge_strategy = app.wedge_data(value,:);
%             wedgedata1 = num2str(app.wedge_data);
%             wedgedata2 = cell2mat(app.wedge_data);            
            wedge = [num2str(value), ' ', char(app.wedge_data{value, index})];
                app.SelectedWedgesTextArea.Value = [app.SelectedWedgesTextArea.Value; wedge];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesFileNameWithoutQuotesEditFieldLabel.Text = 'Wedges File Name (Without Quotes)';
            app.WedgesFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesFileNameWithoutQuotesEditField.Value = 'wedges.txt';
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel.Text = 'Constraints File Name (Without Quotes)';
            app.ConstraintsFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyFileNameWithoutQuotesEditFieldLabel.Text = 'Strategy File Name (Without Quotes)';
            app.StrategyFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedWedgesTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedWedgesTextAreaLabel.Text = 'Selected Wedges';
            app.SelectedWedgesTextArea = uitextarea(app.UIFigure);
            app.ConstraintsDataTextAreaLabel = uilabel(app.UIFigure);
            app.ConstraintsDataTextAreaLabel.Text = 'Constraints Data';
            app.ConstraintsDataTextArea = uitextarea(app.UIFigure);
            app.StrategyDataTextAreaLabel = uilabel(app.UIFigure);
            app.StrategyDataTextAreaLabel.Text = 'Strategy Data';
            app.StrategyDataTextArea = uitextarea(app.UIFigure);
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Text = 'Read Files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end